classdef minimos_cuadrados < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        CalcButton                   matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        ECMEditFieldLabel            matlab.ui.control.Label
        ECMEditField                 matlab.ui.control.EditField
        GraphicAxes                  matlab.ui.control.UIAxes
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        GenerardatosButton           matlab.ui.control.Button
        LimpiarButton                matlab.ui.control.Button
        GridLayout7                  matlab.ui.container.GridLayout
        XPoints                      matlab.ui.control.Table
        YPoints                      matlab.ui.control.Table
        MtododemnimoscuadradosPanel  matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        GridLayout9                  matlab.ui.container.GridLayout
        CBLinearApprox               matlab.ui.control.CheckBox
        CBCuadraticApprox            matlab.ui.control.CheckBox
        CBCubicApprox                matlab.ui.control.CheckBox
        GridLayout10                 matlab.ui.container.GridLayout
        CuadraticApprox              matlab.ui.control.TextArea
        LinearApprox                 matlab.ui.control.TextArea
        CubicApprox                  matlab.ui.control.TextArea
    end

    
    methods (Access = private)
       
        function cleanData(app)
            app.ECMEditField.Value = "";
            app.XPoints.Data = [0];
            app.YPoints.Data = [0];
            app.LinearApprox.Value = "Solución linear";
            app.CuadraticApprox.Value = "Solución cuadrática";
            app.CubicApprox.Value = "Solución cúbica";
            cla(app.GraphicAxes);
        end
        
        function fx = castToFuncion(~, str)
            fx = strrep(lower(str), '^', '.^');
            fx = strrep(fx, 'sen', 'sin');
            fx = strrep(fx, ' ', '');
            for i = 0:9
                expression = strcat(num2str(i),'x');
                fx = regexprep(fx, expression, strcat(num2str(i), '*x'));
            end
            
            try                
                fx = str2func(strcat('@(x)', fx));
                fx(0);
            catch ME
                disp("Error");
                disp(ME)
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cleanData(app);
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cleanData(app);
        end

        % Cell edit callback: XPoints
        function XPointsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if isnan(newData)
                app.XPoints.Data(indices(1)) = 0;
                return;
            elseif indices(1) < length(app.XPoints.Data)
                return;
            end
            
            app.XPoints.Data = [app.XPoints.Data; 0];
            
            if length(app.YPoints.Data) < length(app.XPoints.Data)
                app.YPoints.Data = [app.YPoints.Data; 0];
            end
            
            
        end

        % Cell edit callback: YPoints
        function YPointsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if isnan(newData)
                app.YPoints.Data(indices(1)) = 0;
                return;
            elseif indices(1) < length(app.YPoints.Data)
                return;
            end
            
            app.YPoints.Data = [app.YPoints.Data; 0];
            
            if length(app.XPoints.Data) < length(app.YPoints.Data)
                app.XPoints.Data = [app.XPoints.Data; 0];
            end
        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
            x = app.XPoints.Data(1:end -1);
            y = app.YPoints.Data(1:end -1);
            
            w = (x' * x)^(-1) * x' * y;
            
            min_value = min(x);
            max_value = max(x);
            padding = (max_value - min_value) / 5;
            xlim(app.GraphicAxes, [(min_value - padding) (max_value + padding)]);
            
            y_min = min(y);
            y_max = max(y);
            padding = (max_value - min_value) / 5;
            ylim(app.GraphicAxes, [(y_min - padding) (y_max + padding)]);
            
            hold(app.GraphicAxes);
            
            if app.CBLinearApprox.Value
                A = [ones(size(x))'; x']';
                R = mpower((A' * A), -1) * (A' * y);
                
                str = strcat(num2str(R(2), 10), " * x + ", num2str(R(1), 10));
                fx = castToFuncion(app, str);
                plot(app.GraphicAxes, x, fx(x));
                
                str = strcat("y(x) = ", str);
                app.LinearApprox.Value = ["Solución linear"; newline; str];
            end
            
            if app.CBCuadraticApprox.Value
                A = [ones(size(x))'; x'; x.^2']';
                R = mpower((A' * A), -1) * A' * y;
                str = strcat(num2str(R(3), 10), " * x ^ 2 + ",...
                    num2str(R(2), 10), " * x + ", ...
                    num2str(R(1), 10));
                
                fx = castToFuncion(app, str);
                plot(app.GraphicAxes, x, fx(x));
                
                str = strcat("y(x) = ", str);
                app.CuadraticApprox.Value = ["Solución cuadrática"; newline; str];
            end
            
            if app.CBCubicApprox.Value
                A = [ones(size(x))'; x'; x.^2'; x.^3']';
                R = mpower((A' * A), -1) * A' * y;
                str = strcat(num2str(R(4), 8), " * x ^ 3 + ",...
                    num2str(R(3), 10), " * x ^ 2 + ",...
                    num2str(R(2), 10), " * x  + ", ...
                    num2str(R(1), 10));
                
                fx = castToFuncion(app, str);
                plot(app.GraphicAxes, x, fx(x));
                
                str = strcat("y(x) = ", str);
                app.CubicApprox.Value = ["Solución cúbica"; newline; str];
                
            end
            plot(app.GraphicAxes, x, y, 'o');
            app.ECMEditField.Value = sprintf('%.12g', w);
            
        end

        % Button pushed function: GenerardatosButton
        function GenerardatosButtonPushed(app, event)
            app.XPoints.Data = [0:0.25:2.25 0]';
            app.YPoints.Data = [1 1.2840 1.6487 2.117 2.7183 3.3178 4.077 4.7569 5.4366 6.1161 0]';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.CalcButton = uibutton(app.GridLayout3, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Aproximar';
            app.Panel = uipanel(app.GridLayout3);
            app.ECMEditFieldLabel = uilabel(app.Panel);
            app.ECMEditFieldLabel.Tooltip = {'Error cuadrático medio'};
            app.ECMEditFieldLabel.Text = 'ECM';
            app.ECMEditField = uieditfield(app.Panel, 'text');
            app.ECMEditField.Editable = 'off';
            app.GraphicAxes = uiaxes(app.GridLayout2);
            title(app.GraphicAxes, 'Gráfica')
            xlabel(app.GraphicAxes, 'X')
            ylabel(app.GraphicAxes, 'Y')
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.GenerardatosButton = uibutton(app.GridLayout6, 'push');
            app.GenerardatosButton.ButtonPushedFcn = createCallbackFcn(app, @GenerardatosButtonPushed, true);
            app.GenerardatosButton.Text = 'Generar datos';
            app.LimpiarButton = uibutton(app.GridLayout6, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.XPoints = uitable(app.GridLayout7);
            app.XPoints.ColumnName = {'x'};
            app.XPoints.ColumnEditable = true;
            app.XPoints.CellEditCallback = createCallbackFcn(app, @XPointsCellEdit, true);
            app.YPoints = uitable(app.GridLayout7);
            app.YPoints.ColumnName = {'y'};
            app.YPoints.ColumnEditable = true;
            app.YPoints.CellEditCallback = createCallbackFcn(app, @YPointsCellEdit, true);
            app.MtododemnimoscuadradosPanel = uipanel(app.GridLayout4);
            app.MtododemnimoscuadradosPanel.Title = 'Método de mínimos cuadrados';
            app.GridLayout8 = uigridlayout(app.MtododemnimoscuadradosPanel);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.CBLinearApprox = uicheckbox(app.GridLayout9);
            app.CBLinearApprox.Text = 'Aproximación linear';
            app.CBCuadraticApprox = uicheckbox(app.GridLayout9);
            app.CBCuadraticApprox.Text = 'Aproximación cuadrática';
            app.CBCubicApprox = uicheckbox(app.GridLayout9);
            app.CBCubicApprox.Text = 'Aproximación cúbica';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.CuadraticApprox = uitextarea(app.GridLayout10);
            app.LinearApprox = uitextarea(app.GridLayout10);
            app.CubicApprox = uitextarea(app.GridLayout10);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = minimos_cuadrados

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end