classdef muller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        table                matlab.ui.control.Table
        Title                matlab.ui.control.Label
        P2EditFieldLabel     matlab.ui.control.Label
        P2_txt               matlab.ui.control.NumericEditField
        P1EditFieldLabel     matlab.ui.control.Label
        P1_txt               matlab.ui.control.NumericEditField
        graphics_plot        matlab.ui.control.UIAxes
        fxEditFieldLabel     matlab.ui.control.Label
        fx_txt               matlab.ui.control.EditField
        RacesTextAreaLabel   matlab.ui.control.Label
        results              matlab.ui.control.TextArea
        calculate            matlab.ui.control.Button
        ErrorEditFieldLabel  matlab.ui.control.Label
        error_txt            matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function maxOrMin = getMaxOrMin(app)
            global dfx;
            syms x;
            maxOrMin = solve(dfx == 0, x,'Real',true);
        end
        
        function [P1, P2] = getPoints(app)
            global fx;
            
            P1 = app.P1_txt.Value;
            P2 = app.P2_txt.Value;
            
            if P1 == P2
                ME = MException('Points:invalidValues', ...
                    'P1 y P2 deben ser diferentes, por favor selcciona un par de puntos distintos');
                throw(ME);
            end
            
            if P1 > P2
                app.P1_txt.Value = P2;
                app.P2_txt.Value = P1;
                P1 = P2;
                P2 = app.P2_txt.Value;
            end
            
            plot(app.graphics_plot, P1-1:0.1:P2+1, fx(P1-1:0.1:P2+1));
            
            global dfx;
            dfx = diff(sym(fx));
            
            if fx(P1) * fx(P2) > 0
                getMaxOrMin(app)
                if (subs(dfx, P1) * subs(dfx, P2) > 0) % || (fx(P1) * fx(getMaxOrMin(app)) < 0)
                    ME = MException('suggestedRange:invalidValue', ...
                        ['En el intervalo [%f, %f] se tienen 0 o más de 2 intersecciones con el eje x', ...
                        newline, 'Por favor cambia tu intervalo'],P1, P2);
                    throw(ME);
                end
            end
            
        end
        
        function fx = castToFuncion(app)
            fx = strrep(lower(app.fx_txt.Value), '^', '.^');
            fx = strrep(fx, 'sen', 'sin');
            for i = 0:9
                expression = strcat(num2str(i),'x');
                fx = regexprep(fx, expression, strcat(num2str(i), '*x'));
            end
            
            try                
                fx = str2func(['@(x)' fx]);
                fx(0);
            catch ME
                ME = MException('function:invalidSyntax', ...
                    ['La función "', app.fx_txt.Value,'" debe ser una función de x', ...
                    newline, 'Por favor cambia tu función']);
                throw(ME);
            end
            
        end
        
        function row = formatRow(app, row)
            for i = 1:numel(row)
                if(isnumeric(row{i}))
                    tempStr = sprintf('%.8g', row{i});
                    row{i} = tempStr;
                end
            end
        end
        
        function calcuteRootByMuller(app, P0, P1, error)
            global fx;
            P2 = (P0 + P1) / 2;
            plot(app.graphics_plot, P0, fx(P0), 'o');
            plot(app.graphics_plot, P1, fx(P1), 'o');
            
            data = [];
            
            if fx(P0) * fx(P1) * fx(P2) == 0
                app.table.Data = formatRow(app, ...
                    {P0, P1, P2, fx(P0), fx(P1), fx(P2), '-', '-', '-', '-', '-', '-', '-', '-' });    
                plot(app.graphic_plot, P2, fx(P2), 'o');
                return;
            end
            while true
                
                h0 = P1 - P0;
                h1 = P2 - P1;
                
                d0 = (fx(P1) - fx(P0))/(h0);
                d1 = (fx(P2) - fx(P1))/(h1);
                
                A = (d1 - d0) / (h1 + h0);
                B = A * h1 + d1;
                C = fx(P2);
                fx(P2);
                
                err = abs( (P2 - P1) / P2 );
                
                newRow = formatRow(app,  ...
                   {P0, P1, P2, fx(P0), fx(P1), fx(P2), h0, h1, d0, d1, A, B, C, err});
               
               data = [data; newRow];
               plot(app.graphics_plot, P2, fx(P2), 'o');
               
               if err < error || (abs(fx(P2)) < error && abs(P2-P1) < error)
                   app.results.Value = ['Raíz encontrada en x = ' sprintf('%.32g', P2)...
                       newline 'Con valor de ' sprintf('%.32g', fx(P2))];
                    break;
               end
               
               P0 = P1;
               P1 = P2;
               case_1 = -2 * C / (B + sqrt(B ^ 2 - 4 * A * C)) + P2;
               case_2 = -2 * C / (B - sqrt(B ^ 2 - 4 * A * C)) + P2;
               
               if abs(P2 - case_1) > abs(P2 - case_2)
                   P2 = case_2;
               else
                   P2 = case_1;
               end
            end
            app.table.Data = data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculate
        function calculatePushed(app, event)
            global fx;
            cla(app.graphics_plot);
            hold(app.graphics_plot);
            app.table.Data = [];
            
            error = app.error_txt.Value;
            
            if error < 0
                error = abs(error);
                app.error_txt.Value = error;
            elseif error == 0
                message = ['Es inseguro igualar el error mínimo a 0' newline '¿Desea cambiarlo a 1E-15?'];
                selection = uiconfirm(app.UIFigure,message,'Posible ciclo infinito',...
                        'Icon','warning');
                if strcmpi(selection, 'OK')
                   error = 1E-15;
                else
                    return;
                end
            end
            
            try
                fx = castToFuncion(app);
                [P1, P2] = getPoints(app);
            catch ME
                warndlg(ME.message, ME.identifier);
                return;
            end
            
            calcuteRootByMuller(app, P1, P2, error);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.table = uitable(app.UIFigure);
            app.table.ColumnName = {'x0'; 'x1'; 'x2'; 'f(x0)'; 'f(x1)'; 'f(x2)'; 'h0'; 'h1'; 'd0'; 'd1'; 'a'; 'b'; 'c'; 'er'};
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Método de Müller';
            app.P2EditFieldLabel = uilabel(app.UIFigure);
            app.P2EditFieldLabel.Text = 'P2';
            app.P2_txt = uieditfield(app.UIFigure, 'numeric');
            app.P1EditFieldLabel = uilabel(app.UIFigure);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1_txt = uieditfield(app.UIFigure, 'numeric');
            app.graphics_plot = uiaxes(app.UIFigure);
            title(app.graphics_plot, 'Gráfica')
            xlabel(app.graphics_plot, 'X')
            ylabel(app.graphics_plot, 'Y')
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fx_txt = uieditfield(app.UIFigure, 'text');
            app.RacesTextAreaLabel = uilabel(app.UIFigure);
            app.RacesTextAreaLabel.Text = 'Raíces';
            app.results = uitextarea(app.UIFigure);
            app.calculate = uibutton(app.UIFigure, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculatePushed, true);
            app.calculate.Text = 'Calcular';
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.error_txt = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = muller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end