classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        graphic_plot         matlab.ui.control.UIAxes
        fxEditFieldLabel     matlab.ui.control.Label
        fx_txt               matlab.ui.control.EditField
        NR_tbl               matlab.ui.control.Table
        secant_tbl           matlab.ui.control.Table
        ErrorEditFieldLabel  matlab.ui.control.Label
        error_txt            matlab.ui.control.NumericEditField
        P1EditFieldLabel     matlab.ui.control.Label
        P1_txt               matlab.ui.control.NumericEditField
        Calculate_btn        matlab.ui.control.StateButton
        P2EditFieldLabel     matlab.ui.control.Label
        P2_txt               matlab.ui.control.NumericEditField
        RacesTextAreaLabel   matlab.ui.control.Label
        response             matlab.ui.control.TextArea
    end

    
    methods (Access = public)
        
        function relativeError = relativeError(~, P1, P2)
            relativeError = abs((P2-P1)/P2);
            return
        end
        
        function changePoints(app, P2)
            app.P2_txt.Value = app.P1_txt.Value;
            app.P1_txt.Value = P2;
        end
        
        function castToFuncion = castToFuncion(app)
            castToFuncion = strrep(app.fx_txt.Value, '^', '.^');
            for i = 0:9
                expression = strcat(num2str(i),'x');
                castToFuncion = regexprep(castToFuncion, expression, strcat(num2str(i), '*x'));
            end
            return
        end
        
        function x1 = calculateNewtonRaphsonApprox(app, x0, error)
            global fx;
            global dfx;
%             hold(app.graphic_plot);
            
            while true
                if fx(x0) == 0
                    break;
                end
                x1 = cast(x0 - fx(x0) / subs(dfx, x0), 'double');
                er = abs( (x1 - x0) / x1 );
                new_data = {x0, x1, fx(x1), er};
                for i = 1:numel(new_data)
                    if(isnumeric(new_data{i}))
                        tempStr = sprintf('%.16g', new_data{i});
                        new_data{i} = tempStr;
                    end
                end
                
                app.NR_tbl.Data = [app.NR_tbl.Data; new_data];
                plot(app.graphic_plot, x1, fx(x1), 'or');
                
                if er < error || (abs(fx(x1)) < error && abs(x1-x0) < error)
                    break;
                end
                x0 = x1;
            end
        end
        
        function x2 = calculateSecantApprox(app, x0, x1, error)
            global fx;
%             hold(app.graphic_plot);
            while true
                if fx(x0) == 0
                    break;
                end
                x2 = cast(x1 - (fx(x1))*(x1 - x0) / (fx(x1) - fx(x0)), 'double');
                er = abs( (x2 - x1) / x2 );
                new_data = {x0, x1, x2, fx(x1), er};
                for i = 1:numel(new_data)
                    if(isnumeric(new_data{i}))
                        tempStr = sprintf('%.16g', new_data{i});
                        new_data{i} = tempStr;
                    end
                end
                
                app.secant_tbl.Data = [app.secant_tbl.Data; new_data];
                
                if er < error || (abs(fx(x2)) < error && abs(x2-x1) < error)
                    break;
                end
                plot(app.graphic_plot, x2, fx(x2), 'c*');
                
                x0 = x1;
                x1=x2;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Calculate_btn
        function Calculate_btnValueChanged(app, event)
            func = castToFuncion(app);
            app.NR_tbl.Data = [];
            app.secant_tbl.Data = [];
            
            global fx;
            try
                fx = str2func(['@(x)' func]);
            catch ME
                opts = struct('WindowStyle','modal',...
                    'Interpreter', 'none');
                errordlg("La función introducida es inválida", "Error", opts);
                return
            end
            
            global dfx;
            dfx = diff(sym(fx));
            
            P1 = app.P1_txt.Value;
            P2 = app.P2_txt.Value;
            
            if P1 > P2
                P1 = P2;
                P2 = app.P1_txt.Value;
                changePoints(app, P1);
            end
            
            cla(app.graphic_plot);
%             app.graphic_plot.XLim = [P1, P2];
            hold(app.graphic_plot);
            plot(app.graphic_plot, P1-5:0.1:P2+5, fx(P1-5:0.1:P2+5));
            
            if (fx(P1) * fx(P2) > 0 ) && (subs(dfx, P1) * subs(dfx, P2) > 0)
                warndlg('Please check your guessed interval. It must have zero, two or more intersections with x axis');
                return;
            end
            
            error = abs(app.error_txt.Value);
            
            if error == 0
                message = ['Es inseguro igualar el error mínimo a 0' newline '¿Desea cambiarlo a 1E-15?'];
                selection = uiconfirm(app.UIFigure,message,'Posible ciclo infinito',...
                        'Icon','warning');
                if strcmpi(selection, 'OK')
                   error = 1E-15;
                else
                    return;
                end
            end
            
            nr_res = calculateNewtonRaphsonApprox(app, (P1 + P2) / 2, error);
            s_res = calculateSecantApprox(app, P1, P2, error);
            
            app.response.Value = ['Raíz por secante:' ...
                sprintf('%.32g', nr_res) ' f(x) = ' sprintf('%.16g', fx(s_res)) ...
                newline 'Raíz por Newton-Raphson:' ...
                sprintf('%.32g', nr_res) ' f(x) = ' sprintf('%.16g', fx(nr_res))];
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.graphic_plot = uiaxes(app.UIFigure);
            title(app.graphic_plot, 'Gráfica')
            xlabel(app.graphic_plot, 'X')
            ylabel(app.graphic_plot, 'Y')
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fx_txt = uieditfield(app.UIFigure, 'text');
            app.NR_tbl = uitable(app.UIFigure);
            app.NR_tbl.ColumnName = {'x_{i}'; 'x_{i+1}'; 'f(x_{i+1})'; 'error relativo'};
            app.NR_tbl.RowName = {};
            app.secant_tbl = uitable(app.UIFigure);
            app.secant_tbl.ColumnName = {'x_{i-1}'; 'x_{i}'; 'x_{i+1}'; 'f(x_{i+1})'; 'error relativo'};
            app.secant_tbl.RowName = {};
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.error_txt = uieditfield(app.UIFigure, 'numeric');
            app.P1EditFieldLabel = uilabel(app.UIFigure);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1_txt = uieditfield(app.UIFigure, 'numeric');
            app.Calculate_btn = uibutton(app.UIFigure, 'state');
            app.Calculate_btn.ValueChangedFcn = createCallbackFcn(app, @Calculate_btnValueChanged, true);
            app.Calculate_btn.Text = {'Calcular'; ''};
            app.P2EditFieldLabel = uilabel(app.UIFigure);
            app.P2EditFieldLabel.Text = 'P2';
            app.P2_txt = uieditfield(app.UIFigure, 'numeric');
            app.RacesTextAreaLabel = uilabel(app.UIFigure);
            app.RacesTextAreaLabel.Text = 'Raíces';
            app.response = uitextarea(app.UIFigure);
            app.response.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end