classdef gauss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        procedures                      matlab.ui.control.TextArea
        RightPanel                      matlab.ui.container.Panel
        Matrix                          matlab.ui.control.Table
        MatrixResponses                 matlab.ui.control.Table
        NmerodeincgnitasEditFieldLabel  matlab.ui.control.Label
        NumIncognitas                   matlab.ui.control.NumericEditField
        CalcularButton                  matlab.ui.control.Button
        LimpiarButton                   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function changeColumns(app, col1, col2)
            temp = app.Matrix.Data(:, col1);
            app.Matrix.Data(:, col1) =  app.Matrix.Data(:, col2);
            app.Matrix.Data(:, col2) = temp;
            
            temp = app.Matrix.ColumnName(col1);
            app.Matrix.ColumnName(col1) = app.Matrix.ColumnName(col2);
            app.Matrix.ColumnName(col2) =  temp;
        end
        
        function cleanTable(app)
            global sizeM;
            format longEng;
            data = zeros(sizeM, sizeM);
            
            app.Matrix.Data = data;
            app.MatrixResponses.Data = data(:, 1);
            app.procedures.Value = "";
        end
                
        function changeRows(app, row1, row2)
            temp = app.Matrix.Data(row1, :);
            app.Matrix.Data(row1, :) =  app.Matrix.Data(row2, :);
            app.Matrix.Data(row2, :) = temp;
            
            temp = app.MatrixResponses.Data(row1);
            app.MatrixResponses.Data(row1) = app.MatrixResponses.Data(row2);
            app.MatrixResponses.Data(row2) = temp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumIncognitas
        function NumIncognitasValueChanged(app, event)
            global sizeM;
            sizeM = app.NumIncognitas.Value;
            
            column_names = cell(1, sizeM);
            for i = 1 : sizeM
                column_names{1, i} = ['x' num2str(i)];
            end
            cleanTable(app);
            
            app.Matrix.ColumnName = column_names;
        end

        % Cell edit callback: Matrix
        function MatrixCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if isnan(newData)
                app.Matrix.Data(indices(1), indices(2)) = 0;
            end
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if det(app.Matrix.Data) == 0
                warndlg("El sistema de ecuaciones tiene infinitas soluciones", "Revisa tu sistema");
                return;
            end
            
            global sizeM;
            
            Sol = "";
            
            Data = app.Matrix.Data;
            responses = app.MatrixResponses.Data;
                        
            for i = 1 : sizeM
                attemps = sizeM;
                while Data(i, i) == 0
                    if attemps <= i
                        disp("Algo malo sucedió");
                        warndlg("El sistema de ecuaciones tiene infinitas soluciones", "Revisa tu sistema");
                        return;
                    else
                        changeColumns(app, i, attemps);
                        Sol = Sol + [newline newline 'R_' num2str(i) ' <-> R_' num2str(attemps)];
                        Data = app.Matrix.Data;
                        attemps = attemps - 1;
                    end
                end
                
                Sol = Sol + [newline newline 'R_' num2str(i) ' -> R_' num2str(i)...
                    ' / ' num2str(Data(i, i))];
                responses(i) = responses(i) / Data(i,i);
                Data(i, :) = Data(i, :) / Data(i, i);
                

                for j = 1 : sizeM
                    if i ~= j
                        Sol = Sol + [newline 'R_' num2str(j) ' -> R_' num2str(j)...
                            ' - R_' num2str(i) ' * ' num2str(Data(j, i))];
                        responses(j) = responses(j) - responses(i) * Data(j, i);
                        Data(j, :) = Data(j, :) - Data(j, i) * Data(i, :);
                    end
                end
                app.Matrix.Data = Data;
                
            end
            
            app.MatrixResponses.Data = responses;
            app.Matrix.Data = Data;
            app.procedures.Value = Sol;
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cleanTable(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {492, 492};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {245, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Solución';
            app.procedures = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Resolución de ecuaciones por eliminación Gausiana';
            app.Matrix = uitable(app.RightPanel);
            app.Matrix.ColumnName = {''};
            app.Matrix.ColumnEditable = true;
            app.Matrix.CellEditCallback = createCallbackFcn(app, @MatrixCellEdit, true);
            app.MatrixResponses = uitable(app.RightPanel);
            app.MatrixResponses.ColumnName = {'Value'};
            app.MatrixResponses.RowName = {};
            app.MatrixResponses.ColumnEditable = true;
            app.NmerodeincgnitasEditFieldLabel = uilabel(app.RightPanel);
            app.NmerodeincgnitasEditFieldLabel.Text = 'Número de incógnitas';
            app.NumIncognitas = uieditfield(app.RightPanel, 'numeric');
            app.NumIncognitas.ValueChangedFcn = createCallbackFcn(app, @NumIncognitasValueChanged, true);
            app.CalcularButton = uibutton(app.RightPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.RightPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gauss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end