classdef Doolittle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        xResponses                      matlab.ui.control.Table
        yResponses                      matlab.ui.control.Table
        LResponses                      matlab.ui.control.Table
        UResponses                      matlab.ui.control.Table
        RightPanel                      matlab.ui.container.Panel
        Matrix                          matlab.ui.control.Table
        MatrixResponses                 matlab.ui.control.Table
        NmerodeincgnitasEditFieldLabel  matlab.ui.control.Label
        NumIncognitas                   matlab.ui.control.NumericEditField
        CalcularButton                  matlab.ui.control.Button
        LimpiarButton                   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function changeColumns(app, col1, col2)
            temp = app.Matrix.Data(:, col1);
            app.Matrix.Data(:, col1) =  app.Matrix.Data(:, col2);
            app.Matrix.Data(:, col2) = temp;
            
            temp = app.Matrix.ColumnName(col1);
            app.Matrix.ColumnName(col1) = app.Matrix.ColumnName(col2);
            app.Matrix.ColumnName(col2) =  temp;
        end
        
        function cleanTable(app)
            global sizeM;
            format longEng;
            data = zeros(sizeM, sizeM);
            
            app.Matrix.Data = data;
            app.MatrixResponses.Data = data(:, 1);
            app.xResponses.Data = [];
            app.yResponses.Data = [];
            app.UResponses.Data = [];
            app.LResponses.Data = [];
        end
                
        function changeRows(app, row1, row2)
            temp = app.Matrix.Data(row1, :);
            app.Matrix.Data(row1, :) =  app.Matrix.Data(row2, :);
            app.Matrix.Data(row2, :) = temp;
            
            temp = app.MatrixResponses.Data(row1);
            app.MatrixResponses.Data(row1) = app.MatrixResponses.Data(row2);
            app.MatrixResponses.Data(row2) = temp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumIncognitas
        function NumIncognitasValueChanged(app, event)
            global sizeM;
            sizeM = app.NumIncognitas.Value;
            
            column_names = cell(1, sizeM);
            for i = 1 : sizeM
                column_names{1, i} = ['x' num2str(i)];
            end
            cleanTable(app);
            
            app.Matrix.ColumnName = column_names;
        end

        % Cell edit callback: Matrix
        function MatrixCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if isnan(newData)
                app.Matrix.Data(indices(1), indices(2)) = 0;
            end
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if det(app.Matrix.Data) == 0
                warndlg("El sistema de ecuaciones tiene infinitas soluciones", "Revisa tu sistema");
                return;
            end
            
            global sizeM;
            
            Data = app.Matrix.Data;
            responses = app.MatrixResponses.Data;
                        
            [L, U, P] = lu(Data);
            
            y =  L\(P * responses);
            
            x = U\y;
            
            new_data = num2cell(x);
            for j = 1: sizeM
                tempStr = sprintf('%.10g', new_data{j});
                new_data{j} = tempStr;
            end
            
            app.xResponses.Data = new_data;
            app.yResponses.Data = y;
            app.UResponses.Data = U;
            app.LResponses.Data = L;
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cleanTable(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {492, 492};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {312, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Solución';
            app.xResponses = uitable(app.LeftPanel);
            app.xResponses.ColumnName = {'x'};
            app.yResponses = uitable(app.LeftPanel);
            app.yResponses.ColumnName = {'y'};
            app.LResponses = uitable(app.LeftPanel);
            app.LResponses.ColumnName = {'L'};
            app.UResponses = uitable(app.LeftPanel);
            app.UResponses.ColumnName = {'U'};
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Resolución de ecuaciones por factorización LU';
            app.Matrix = uitable(app.RightPanel);
            app.Matrix.ColumnName = {''};
            app.Matrix.ColumnEditable = true;
            app.Matrix.CellEditCallback = createCallbackFcn(app, @MatrixCellEdit, true);
            app.MatrixResponses = uitable(app.RightPanel);
            app.MatrixResponses.ColumnName = {'Value'};
            app.MatrixResponses.RowName = {};
            app.MatrixResponses.ColumnEditable = true;
            app.NmerodeincgnitasEditFieldLabel = uilabel(app.RightPanel);
            app.NmerodeincgnitasEditFieldLabel.Text = 'Número de incógnitas';
            app.NumIncognitas = uieditfield(app.RightPanel, 'numeric');
            app.NumIncognitas.ValueChangedFcn = createCallbackFcn(app, @NumIncognitasValueChanged, true);
            app.CalcularButton = uibutton(app.RightPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.RightPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Doolittle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end