classdef Gauss_seidel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Solution                        matlab.ui.control.Table
        RightPanel                      matlab.ui.container.Panel
        Matrix                          matlab.ui.control.Table
        MatrixResponses                 matlab.ui.control.Table
        NmerodeincgnitasEditFieldLabel  matlab.ui.control.Label
        NumIncognitas                   matlab.ui.control.NumericEditField
        CalcularButton                  matlab.ui.control.Button
        LimpiarButton                   matlab.ui.control.Button
        ToleranciaLabel                 matlab.ui.control.Label
        Error                           matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        function cleanTable(app)
            global sizeM;
            format longEng;
            data = zeros(sizeM, sizeM);
            
            app.Matrix.Data = data;
            app.MatrixResponses.Data = data(:, 1);
            app.Solution.Data = zeros(1, sizeM);
        end
        
        function changeColumns(app, col1, col2)
            temp = app.Matrix.Data(:, col1);
            app.Matrix.Data(:, col1) =  app.Matrix.Data(:, col2);
            app.Matrix.Data(:, col2) = temp;
            
            temp = app.Matrix.ColumnName(col1);
            app.Matrix.ColumnName(col1) = app.Matrix.ColumnName(col2);
            app.Matrix.ColumnName(col2) =  temp;
            
            app.Solution.ColumnName(col1) = app.Solution.ColumnName(col2);
            app.Solution.ColumnName(col2) =  temp;
        end
        
        function changeRows(app, row1, row2)
            temp = app.Matrix.Data(row1, :);
            app.Matrix.Data(row1, :) =  app.Matrix.Data(row2, :);
            app.Matrix.Data(row2, :) = temp;
            
            temp = app.MatrixResponses.Data(row1);
            app.MatrixResponses.Data(row1) = app.MatrixResponses.Data(row2);
            app.MatrixResponses.Data(row2) = temp;
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumIncognitas
        function NumIncognitasValueChanged(app, event)
            global sizeM;
            sizeM = app.NumIncognitas.Value;
            
            column_names = cell(1, sizeM);
            for i = 1 : sizeM
                column_names{1, i} = ['x' num2str(i)];
            end
            cleanTable(app);
            
            app.Matrix.ColumnName = column_names;
            app.Solution.ColumnName = column_names;
        end

        % Cell edit callback: Matrix
        function MatrixCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if isnan(newData)
                app.Matrix.Data(indices(1), indices(2)) = 0;
            end
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            global sizeM;
            
            tolerance = abs(app.Error.Value);
            x = zeros(1, sizeM);
            
            if tolerance == 0
                msg = ["Garantizar un error inifinitamente pequeño o igual a cero" + ...
                    " puede terminar en error, puesto que la solución exacta puede" + ...
                    " nunca alcanzarse." newline "Por lo tanto, la tolerancia será modificada a 10E-5"];
                warndlg(msg, "El error debe ser distinto de 0");
                tolerance = 10E-5;
            end
            app.Error.Value = tolerance;
            
            [~, I] = max(abs(app.Matrix.Data));
            [~, I_] = max(abs(app.Matrix.Data'));
            if length(unique(I)) == sizeM && length(unique(I_)) == sizeM
                for i = 1 : sizeM
                    idx = find(I == i);
                    if idx > i || i == sizeM
                        changeColumns(app, idx, i);
                    end
                end
            else
                try chol(A)
                    ok = true;
                catch ME
                    warndlg("El sistema de ecuaciones ni es definido positivo " + ...
                        "ni es una matriz de diagonal estrictamente dominante. " + ...
                        "Por lo tanto no se puede resolver con Gauss-Seidel", "Revisa tu sistema");
                    return;
                end
            end
            
            A = app.Matrix.Data;
            R = app.MatrixResponses.Data;
            data = num2cell(x);
            error = inf;
            while error > tolerance
                x_old = x;
                for i = 1 : sizeM
                    x(i) = (-A(i, [1:i-1, i+1:end]) * x([1:i-1, i+1:end])' + R(i))/A(i, i);
                end
                new_data = num2cell(x);
                for j = 1: sizeM
                    tempStr = sprintf('%.16g', new_data{j});
                    new_data{j} = tempStr;
                end
                data = [data; new_data];
                error = norm(x_old - x);
            end
            
            app.Solution.Data = data;
            
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cleanTable(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {503, 503};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {514, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Solución';
            app.Solution = uitable(app.LeftPanel);
            app.Solution.ColumnName = {''};
            app.Solution.ColumnEditable = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Resolución de ecuaciones por Gauss-Seidel';
            app.Matrix = uitable(app.RightPanel);
            app.Matrix.ColumnName = {''};
            app.Matrix.ColumnEditable = true;
            app.Matrix.CellEditCallback = createCallbackFcn(app, @MatrixCellEdit, true);
            app.MatrixResponses = uitable(app.RightPanel);
            app.MatrixResponses.ColumnName = {'Value'};
            app.MatrixResponses.RowName = {};
            app.MatrixResponses.ColumnEditable = true;
            app.NmerodeincgnitasEditFieldLabel = uilabel(app.RightPanel);
            app.NmerodeincgnitasEditFieldLabel.Text = 'Número de incógnitas';
            app.NumIncognitas = uieditfield(app.RightPanel, 'numeric');
            app.NumIncognitas.ValueChangedFcn = createCallbackFcn(app, @NumIncognitasValueChanged, true);
            app.CalcularButton = uibutton(app.RightPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.RightPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.ToleranciaLabel = uilabel(app.RightPanel);
            app.ToleranciaLabel.Text = 'Tolerancia';
            app.Error = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gauss_seidel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end